classdef Conv_Img < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RunButton              matlab.ui.control.Button
        DAPIEditFieldLabel     matlab.ui.control.Label
        DAPIEditField          matlab.ui.control.NumericEditField
        ChannelLabel           matlab.ui.control.Label
        DMEditFieldLabel       matlab.ui.control.Label
        DMEditField            matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        GSEditFieldLabel       matlab.ui.control.Label
        GSEditField            matlab.ui.control.NumericEditField
        FileTypeDropDownLabel  matlab.ui.control.Label
        FileTypeDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            if strcmpi(app.CallingApp.DropDown.Value,'Convert Architecture Stacks')
                app.EditFieldLabel.Text = 'DPPIV';
            else
                app.EditFieldLabel.Text = 'BrdU';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            PL = app.CallingApp.Path_List;
            %ADD WAITBAR HERE AT SOME POINT
            for i = 1:numel(PL)
                Path = PL{i};
                Raw_Convert(Path,4,[app.DAPIEditField.Value,app.EditField.Value,app.GSEditField.Value,app.DMEditField.Value],'uint16',app.FileTypeDropDown.Value)
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DAPIEditFieldLabel = uilabel(app.UIFigure);
            app.DAPIEditFieldLabel.Text = 'DAPI';
            app.DAPIEditField = uieditfield(app.UIFigure, 'numeric');
            app.DAPIEditField.Value = 1;
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel';
            app.FileTypeDropDownLabel = uilabel(app.UIFigure);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.UIFigure);
            app.FileTypeDropDown.Items = {'.oib'};
            app.FileTypeDropDown.Value = '.oib';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 2;
            app.GSEditFieldLabel = uilabel(app.UIFigure);
            app.GSEditFieldLabel.Text = 'GS';
            app.GSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GSEditField.Value = 3;
            app.DMEditFieldLabel = uilabel(app.UIFigure);
            app.DMEditFieldLabel.Text = 'DM';
            app.DMEditField = uieditfield(app.UIFigure, 'numeric');
            app.DMEditField.Value = 4;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Conv_Img(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end