classdef V_and_C < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FrameEditFieldLabel       matlab.ui.control.Label
        FrameEditField            matlab.ui.control.NumericEditField
        VisualizeButton           matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        ChannelButtonGroup        matlab.ui.container.ButtonGroup
        c1Button                  matlab.ui.control.ToggleButton
        c2Button                  matlab.ui.control.ToggleButton
        c3Button                  matlab.ui.control.ToggleButton
        c4Button                  matlab.ui.control.ToggleButton
        TextArea                  matlab.ui.control.TextArea
        FirstFrameEditFieldLabel  matlab.ui.control.Label
        FirstFrameEditField       matlab.ui.control.NumericEditField
        CutButton                 matlab.ui.control.Button
        LastFrameEditFieldLabel   matlab.ui.control.Label
        LastFrameEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        CallingApp % Description
        c1
        c2
        c3
        c4
        z_size
        index
        del
        c = 1; %channel that will be shown
    end
    
    properties (Access = private)
        z % the depth of the z stack that is currently being displayed
        Path
    end
    
    methods (Access = private)
        
        function KeyButtonPressed(app,~,hit)
            if strcmpi(hit.Key,'uparrow')
                if app.z > 1
                   app.z = app.z - 1; 
                end
            elseif strcmpi(hit.Key,'downarrow')
                if app.z < app.z_size
                   app.z = app.z + 1; 
                end
            elseif strcmpi(hit.Key,'leftarrow')
                if app.c > 1
                    app.c = app.c - 1;
                end
            elseif strcmpi(hit.Key,'rightarrow')
                if app.c < 4
                    app.c = app.c + 1;
                end
            end
            app.FrameEditField.Value = app.z;
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
                app.c1Button.Value = 1;
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
                app.c2Button.Value = 1;
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
                app.c3Button.Value = 1;
            else
                imshow(app.c4(:,:,app.z));
                app.c4Button.Value = 1;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Path, ef, index, del)
            app.CallingApp = mainapp;
            %import all of the images
            app.c1 = Import_Tiff_3d(Path,'c1.tif');
            app.c2 = Import_Tiff_3d(Path,'c2.tif');
            app.c3 = Import_Tiff_3d(Path,'c3.tif');
            app.c4 = Import_Tiff_3d(Path,'c4.tif');
            app.Path = Path;
            app.z_size = size(app.c1,3);
            app.TextArea.Value = ef;
            app.index = index;
            app.del = del;
            app.Label.Text = [' \ ' num2str(app.z_size)];
            app.LastFrameEditField.Value = app.z_size;
            app.FrameEditField.Value = 1;
            figure('KeyPressFcn',@app.KeyButtonPressed);
            imshow(app.c1(:,:,1));
            app.z = 1;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            %This function recreates the image if you x out of it
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
                app.c1Button.Value = 1;
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
                app.c2Button.Value = 1;
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
                app.c3Button.Value = 1;
            else
                imshow(app.c4(:,:,app.z));
                app.c4Button.Value = 1;
            end
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            %forces the editfield to be a value that is within the range of the image's z size
            if value > app.z_size
               app.FrameEditField.Value = app.z_size;
            elseif Value < 1
                app.FrameEditField.Value = 1;
            end
            app.z = app.FrameEditField.Value;
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
                app.c1Button.Value = 1;
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
                app.c2Button.Value = 1;
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
                app.c3Button.Value = 1;
            else
                imshow(app.c4(:,:,app.z));
                app.c4Button.Value = 1;
            end
        end

        % Value changed function: FirstFrameEditField
        function FirstFrameEditFieldValueChanged(app, event)
            value = app.FirstFrameEditField.Value;
            if value < 1 || value > app.z_size - 1
                app.FirstFrameEditField.Value = 1;
            end
            app.FirstFrameEditField.Value = round(app.FirstFrameEditField.Value);          
        end

        % Value changed function: LastFrameEditField
        function LastFrameEditFieldValueChanged(app, event)
            value = app.LastFrameEditField.Value;
            if value < 1 || value > app.z_size - 1
                app.LastFrameEditField.Value = app.z_size;
            end
            app.LastFrameEditField.Value = round(app.LastFrameEditField.Value);
        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
            if app.c1Button.Value == 1
                app.c = 1;
            elseif app.c2Button.Value == 1
                app.c = 2;
            elseif app.c3Button.Value == 1
                app.c = 3;
            else
                app.c = 4;
            end
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
                app.c1Button.Value = 1;
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
                app.c2Button.Value = 1;
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
                app.c3Button.Value = 1;
            else
                imshow(app.c4(:,:,app.z));
                app.c4Button.Value = 1;
            end
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
           cut_before = app.FirstFrameEditField.Value - 1;
           cut_after = app.z_size - app.LastFrameEditField.Value;
           if cut_before > 0 || cut_after > 0
             cut_frames(app.Path,cut_before,cut_after)
           end
           if app.del == 1
               app.CallingApp.ListofDataSetsListBox.Items(app.index) = [];
           end
           close all
           delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/ #';
            app.ChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.c1Button = uitogglebutton(app.ChannelButtonGroup);
            app.c1Button.Text = 'c1';
            app.c1Button.Value = true;
            app.c2Button = uitogglebutton(app.ChannelButtonGroup);
            app.c2Button.Text = 'c2';
            app.c3Button = uitogglebutton(app.ChannelButtonGroup);
            app.c3Button.Text = 'c3';
            app.c4Button = uitogglebutton(app.ChannelButtonGroup);
            app.c4Button.Text = 'c4';
            app.TextArea = uitextarea(app.UIFigure);
            app.FirstFrameEditFieldLabel = uilabel(app.UIFigure);
            app.FirstFrameEditFieldLabel.Text = 'First Frame';
            app.FirstFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstFrameEditField.ValueChangedFcn = createCallbackFcn(app, @FirstFrameEditFieldValueChanged, true);
            app.FirstFrameEditField.Value = 1;
            app.CutButton = uibutton(app.UIFigure, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Text = 'Cut';
            app.LastFrameEditFieldLabel = uilabel(app.UIFigure);
            app.LastFrameEditFieldLabel.Text = 'Last Frame';
            app.LastFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.LastFrameEditField.ValueChangedFcn = createCallbackFcn(app, @LastFrameEditFieldValueChanged, true);
            app.LastFrameEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = V_and_C(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end