classdef V < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FrameEditFieldLabel  matlab.ui.control.Label
        FrameEditField       matlab.ui.control.NumericEditField
        VisualizeButton      matlab.ui.control.Button
        Label                matlab.ui.control.Label
        ChannelButtonGroup   matlab.ui.container.ButtonGroup
        BileCaniculiButton   matlab.ui.control.ToggleButton
        SinusoidButton       matlab.ui.control.ToggleButton
        CVButton             matlab.ui.control.ToggleButton
        PVButton             matlab.ui.control.ToggleButton
        NucCentersButton     matlab.ui.control.ToggleButton
        TextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        CallingApp % Description
        c1
        c2
        c3
        c4
        c5
        z_size
        c = 1;
    end
    
    properties (Access = private)
        z % Description
        Path % Description
    end
    
    methods (Access = private)
        
        function KeyButtonPressed(app,~,hit)
            if strcmpi(hit.Key,'uparrow')
                if app.z > 1
                   app.z = app.z - 1; 
                end
            elseif strcmpi(hit.Key,'downarrow')
                if app.z < app.z_size
                   app.z = app.z + 1; 
                end
            elseif strcmpi(hit.Key,'leftarrow')
                if app.c > 1
                    app.c = app.c - 1;
                end
            elseif strcmpi(hit.Key,'rightarrow')
                if app.c < 5
                    app.c = app.c + 1;
                end
            end
            app.FrameEditField.Value = app.z;
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
                app.CVButton.Value = 1;
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
                app.PVButton.Value = 1;
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
                app.BileCaniculiButton.Value = 1;
            elseif app.c == 4
                imshow(app.c4(:,:,app.z));
                app.SinusoidButton.Value = 1;
            else
                imshow(app.c5(:,:,app.z));
                app.NucCentersButton.Value = 1;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Path, ef)
            app.CallingApp = mainapp;
            app.c1 = Import_Tiff_3d(Path,'CV_Mask.tif');
            app.c2 = Import_Tiff_3d(Path,'PV_Mask.tif');
            app.c3 = Import_Tiff_3d(Path,'Bile.tif');
            app.c4 = Import_Tiff_3d(Path,'Sinusoids.tif');
            app.c5 = Import_Tiff_3d(Path,'Hepa_Nuclei_Seg.tif');
            app.Path = Path;
            app.z_size = size(app.c1,3);
            app.TextArea.Value = ef;
            app.Label.Text = [' \ ' num2str(app.z_size)];
            app.FrameEditField.Value = 1;
            figure('KeyPressFcn',@app.KeyButtonPressed);
            imshow(app.c1(:,:,1));
            app.z = 1;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
            elseif app.c == 4
                imshow(app.c4(:,:,app.z));
            else
                imshow(app.c5(:,:,app.z));
            end
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            if value > app.z_size || Value < 1
               app.FrameEditField.Value = app.z_size;
            end
            app.FrameEditField.Value = round(app.FrameEditField.Value);
            app.z = app.FrameEditField.Value;
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
            elseif app.c == 4
                imshow(app.c4(:,:,app.z));
            else
                imshow(app.c5(:,:,app.z));
            end
        end

        % Selection changed function: ChannelButtonGroup
        function ChannelButtonGroupSelectionChanged(app, event)
            if app.CVButton.Value == 1
                app.c = 1;
            elseif app.PVButton.Value == 1
                app.c = 2;
            elseif app.BileCaniculiButton.Value == 1
                app.c = 3;
            elseif app.SinusoidButton.Value == 1
                app.c = 4;
            else
                app.c = 5;
            end
            if isempty(findobj('type','figure'))
                figure('KeyPressFcn',@app.KeyButtonPressed);
            end
            if app.c == 1
                imshow(app.c1(:,:,app.z));
            elseif app.c == 2
                imshow(app.c2(:,:,app.z));
            elseif app.c == 3
                imshow(app.c3(:,:,app.z));
            elseif app.c == 4
                imshow(app.c4(:,:,app.z));
            else
                imshow(app.c5(:,:,app.z));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/ #';
            app.ChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelButtonGroupSelectionChanged, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.BileCaniculiButton = uitogglebutton(app.ChannelButtonGroup);
            app.BileCaniculiButton.Text = 'Bile Caniculi';
            app.SinusoidButton = uitogglebutton(app.ChannelButtonGroup);
            app.SinusoidButton.Text = 'Sinusoid';
            app.CVButton = uitogglebutton(app.ChannelButtonGroup);
            app.CVButton.Text = 'CV';
            app.CVButton.Value = true;
            app.PVButton = uitogglebutton(app.ChannelButtonGroup);
            app.PVButton.Text = 'PV';
            app.NucCentersButton = uitogglebutton(app.ChannelButtonGroup);
            app.NucCentersButton.Text = 'Nuc  Centers';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = V(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end