classdef Liver_Segmentation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectMainFolderButton      matlab.ui.control.Button
        MainFolderTextAreaLabel     matlab.ui.control.Label
        MainFolderTextArea          matlab.ui.control.TextArea
        ListofDataSetsListBoxLabel  matlab.ui.control.Label
        ListofDataSetsListBox       matlab.ui.control.ListBox
        SelectButton                matlab.ui.control.Button
        DropTask                    matlab.ui.control.DropDown
        ButtonGroup                 matlab.ui.container.ButtonGroup
        SelectedDataSetButton       matlab.ui.control.ToggleButton
        AllDataSetsButton           matlab.ui.control.ToggleButton
        RuninParallelCheckBox       matlab.ui.control.CheckBox
        MaxCoresEditFieldLabel      matlab.ui.control.Label
        MaxCoresEditField           matlab.ui.control.NumericEditField
        MaxRamGBEditFieldLabel      matlab.ui.control.Label
        MaxRamGBEditField           matlab.ui.control.NumericEditField
        RunButton                   matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        DeleteButton                matlab.ui.control.Button
        DropStain                   matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MAB % Description
        ncore % Description
        directory =[]; % Description
        Conv_Img_App % Description
        V_and_C_App % Description
        V_App % Description
        end_folders % Description
    end
    
    properties (Access = public)
        Path_List % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxRamGBEditFieldLabel.Visible = 'off';
            app.MaxCoresEditFieldLabel.Visible = 'off';
            if ~isdeployed
                str = which('Liver_Segmentation_App');
                [pathstr] = fileparts(str);
                addpath(genpath(pathstr))
            end
        end

        % Button pushed function: SelectMainFolderButton
        function SelectMainFolderButtonPushed(app, event)
            dir = uigetdir(app.directory);
            app.directory = dir;
            app.MainFolderTextArea.Value = dir;
            [app.Path_List,app.end_folders] = find_paths(dir);
            app.ListofDataSetsListBox.Items = app.end_folders;
            figure(app.UIFigure);
            app.DropStain.Visible = 'on';
            app.DropTask.Visible = 'on';
            app.SelectButton.Visible = 'on';
        end

        % Value changed function: RuninParallelCheckBox
        function RuninParallelCheckBoxValueChanged(app, event)
            value = app.RuninParallelCheckBox.Value;
            [user,~] = memory;
            if value == 1
                app.MAB = user.MaxPossibleArrayBytes/(10^9);
                app.ncore = feature('numcores');
                app.MaxRamGBEditField.Visible = 'on';
                app.MaxRamGBEditField.Enable = 'on';
                app.MaxRamGBEditFieldLabel.Visible = 'on';
                app.MaxRamGBEditField.Value = app.MAB;
                app.MaxCoresEditField.Value = app.ncore;
                app.MaxCoresEditFieldLabel.Visible = 'on';
                app.MaxCoresEditField.Visible = 'on';
                app.MaxCoresEditField.Enable = 'on';
            else
                app.MaxRamGBEditField.Visible = 'off';
                app.MaxRamGBEditField.Enable = 'off';
                app.MaxRamGBEditFieldLabel.Visible = 'off';
                app.MaxCoresEditFieldLabel.Visible = 'off';
                app.MaxCoresEditField.Visible = 'off';
                app.MaxCoresEditField.Enable = 'off';
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.DropDown.Visible = 'on';
            if strcmpi(app.DropStain.Value,'Raw')
                if strcmpi(app.DropTask.Value,'Convert to Individual Channels')
                    app.DropDown.Items = strsplit('Convert Architecture Stacks,Convert Proliferation Stacks',','); 
                else
                    app.DropDown.Items = strsplit('Cut and Remove from List,Cut and Keep in List',',');
                    app.SelectedDataSetButton.Value = 1;
                end
            elseif strcmpi(app.DropStain.Value,'Architecture')
                if strcmpi(app.DropTask.Value,'Segment')
                    app.DropDown.Items = strsplit('Segment All,Segment Architecture Only,Segment Nuclei Only,Segment Cells Only,Test',',');
                elseif strcmpi(app.DropTask.Value,'Gather Statistics')
                    app.DropDown.Items = strsplit('Gather All Stats,Gather Sinusoid Stats,Gather Bile Stats,Gather Cell Stats,Gather Volume Stats,Test',',');
                elseif strcmpi(app.DropTask.Value,'Collect Statistics')
                    app.DropDown.Items = strsplit('Collect All Stats,Collect Sinusoid Stats,Collect Bile Stats,Collect Cell Stats,Collect Volume Stats',',');
                elseif strcmpi(app.DropTask.Value,'Visualize')
                    app.DropDown.Items = {'2d Visualization'};
                else
                    app.DropDown.Items = {'Not Added Yet'};
                end
            else
                if strcmpi(app.DropTask.Value,'Segment')
                    app.DropDown.Items = strsplit('Segment All,Segment Architecture Only,Segment Nuclei Only,Test',',');
                elseif strcmpi(app.DropTask.Value,'Gather Statistics')
                    app.DropDown.Items = strsplit('Gather BrdU Stats,Test',',');
                elseif strcmpi(app.DropTask.Value,'Collect Statistics')
                    app.DropDown.Items = strsplit('Collect BrdU Stats',',');
                elseif strcmpi(app.DropTask.Value,'Visualize')
                    app.DropDown.Items = {'2d Visualization'};
                else
                    app.DropDown.Items = {'Not Added Yet'};
                end
            end
            app.RunButton.Enable = 'on';
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            [~, index] = ismember(app.ListofDataSetsListBox.Value, app.ListofDataSetsListBox.Items);
            app.end_folders(index) = [];
            app.Path_List(index) = [];
            app.ListofDataSetsListBox.Items = app.end_folders;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            stain = app.DropStain.Value;
            task = app.DropTask.Value;
            specific_task = app.DropDown.Value;
            parallel = app.RuninParallelCheckBox.Value;
            PL = app.Path_List;
            EF = app.end_folders;
            %% if the selection of processesing an individual file is chosen, set the pathlist as the first selected item
            if app.SelectedDataSetButton.Value == 1 
                [~, index] = ismember(app.ListofDataSetsListBox.Value, app.ListofDataSetsListBox.Items);
                PL = PL(index);
                EF = EF(index);
            end
            if ~isempty(PL) %If there is data in the path list
                if strcmpi(stain,'Raw') %All Raw
                    if strcmpi(task,'Convert to Individual Channels')         
                        app.Conv_Img_App = Conv_Img(app); %App to convert microscope images to individual channel, 3d image stacks
                    else
                        if strcmpi(specific_task,'Cut and Remove from List') %takes the individual channels and allows for you to cut off the "bad stained" parts of the images
                            app.V_and_C_App = V_and_C(app,PL{1},EF{1},index(1),1); %cuts and removes image from list when done
                        else
                            app.V_and_C_App = V_and_C(app,PL{1},EF{1},index(1),0); %cuts image but keeps in list
                        end
                    end
                        
                elseif parallel == 0 %Parallel
                    if strcmpi(stain,'Architecture') %non para Arch
                        if strcmpi(task,'Segment')
                            if strcmpi(specific_task,'Segment All')
                                v = 1;
                            elseif strcmpi(specific_task,'Segment Architecture Only')
                                v = 2;
                            elseif strcmpi(specific_task,'Segment Nuclei Only')
                                v = 3;
                            elseif strcmpi(specific_task,'Segment Cells Only')
                                v = 4;
                            else
                                v = 5;
                            end
                            Seg_Gui(v,PL,EF);
                        elseif strcmpi(task,'Gather Statistics')
                            if strcmpi(specific_task,'Gather All Stats')
                                v = 1;
                            elseif strcmpi(specific_task,'Gather Sinusoid Stats')
                                v = 2;
                            elseif strcmpi(specific_task,'Gather Bile Stats')
                                v = 3;
                            elseif strcmpi(specific_task,'Gather Cell Stats')
                                v = 4;
                            elseif strcmpi(specific_task,'Gather Volume Stats')
                                v = 5;
                            else
                                v = 6;
                            end
                            Gather_Gui(v,PL,EF);
                        elseif strcmpi(task,'Collect Statistics')
                            if strcmpi(specific_task,'Collect All Stats')
                                v = 1;
                            elseif strcmpi(specific_task,'Collect Sinusoid Stats')
                                v = 2;
                            elseif strcmpi(specific_task,'Collect Bile Stats')
                                v = 3;
                            elseif strcmpi(specific_task,'Collect Cell Stats')
                                v = 4;
                            elseif strcmpi(specific_task,'Collect Volume Stats')
                                v = 5;
                            else
                                v = 6;
                            end
                            Collect_Gui(v,PL,app.directory);
                        elseif strcmpi(task,'Visualize')
                            app.V_App = V(app,PL{1},EF{1});
                        end
                    else %non para Prolif
                        if strcmpi(task,'Segment')
                            if strcmpi(specific_task,'Segment All')
                                v = 1;
                            elseif strcmpi(specific_task,'Segment Architecture Only')
                                v = 2;
                            elseif strcmpi(specific_task,'Segment Nuclei Only')
                                v = 3;
                            else
                                v = 4;
                            end
                            Seg_Gui_Pro(v,PL,EF);
                        elseif strcmpi(task,'Gather Statistics')
                            if strcmpi(specific_task,'Gather BrdU Stats')
                                v = 1;
                            else
                                v = 2;
                            end
%                             Gather_Gui_Pro(v,PL,EF); %NEED TO FIX
                        elseif strcmpi(task,'Collect Statistics')
                            if strcmpi(specific_task,'Collect BrdU Stats')
                                v = 1;
                            else
                                v = 2;
                            end
%                             Collect_Gui_Pro(v,PL,app.directory);  %NEED TO FIX
                        elseif strcmpi(task,'Visualize')
                            app.V_App = V2(app,PL{1},EF{1});     
                        end
                    end
                else %Para
                    if strcmpi(stain,'Architecture') %Para Arch
                        if strcmpi(task,'Segment')
                            if strcmpi(specific_task,'Segment All')
                                v = 1;
                            elseif strcmpi(specific_task,'Segment Architecture Only')
                                v = 2;
                            elseif strcmpi(specific_task,'Segment Nuclei Only')
                                v = 3;
                            elseif strcmpi(specific_task,'Segment Cells Only')
                                v = 4;
                            else
                                v = 5;
                            end
                            n = numel(PL);
                            N_Core = max(min([n,app.MaxCoresEditField.Value,floor(app.MaxRamGBEditField.Value/15)]),1);
                            Seg_Gui_P(N_Core,v,PL,EF);
                        elseif strcmpi(task,'Gather Statistics')                           
                            if strcmpi(specific_task,'Gather All Stats')
                                v = 1;
                            elseif strcmpi(specific_task,'Gather Sinusoid Stats')
                                v = 2;
                            elseif strcmpi(specific_task,'Gather Bile Stats')
                                v = 3;
                            elseif strcmpi(specific_task,'Gather Cell Stats')
                                v = 4;
                            elseif strcmpi(specific_task,'Gather Volume Stats')
                                v = 5;
                            else
                                v = 6;
                            end
                            n = numel(PL);
                            N_Core = max(min([n,app.MaxCoresEditField.Value,floor(app.MaxRamGBEditField.Value/15)]),1);
                            Gather_Gui_P(N_Core,v,PL,EF);
                        elseif strcmpi(task,'Collect Statistics')                           
                            if strcmpi(specific_task,'Collect All Stats')
                                v = 1;
                            elseif strcmpi(specific_task,'Collect Sinusoid Stats')
                                v = 2;
                            elseif strcmpi(specific_task,'Collect Bile Stats')
                                v = 3;
                            elseif strcmpi(specific_task,'Collect Cell Stats')
                                v = 4;
                            elseif strcmpi(specific_task,'Collect Volume Stats')
                                v = 5;
                            else
                                v = 6;
                            end
                            Collect_Gui(v,PL,app.directory);
                        elseif strcmpi(task,'Visualize')
                            app.V_App = V(app,PL{1},EF{1});
                        else
                            
                        end
                    else
                        %% Para Prolif
                        if strcmpi(task,'Segment')
                            if strcmpi(specific_task,'Segment All')
                                v = 1;
                            elseif strcmpi(specific_task,'Segment Architecture Only')
                                v = 2;
                            elseif strcmpi(specific_task,'Segment Nuclei Only')
                                v = 3;
                            else
                                v = 4;
                            end
                            n = numel(PL);
                            N_Core = max(min([n,app.MaxCoresEditField.Value,floor(app.MaxRamGBEditField.Value/15)]),1);
                            Seg_Gui_Pro_P(N_Core,v,PL,EF);
                        elseif strcmpi(task,'Gather Statistics')                           
                            if strcmpi(specific_task,'Gather BrdU Stats')
                                v = 1;
                            else
                                v = 2;
                            end
%                             Gather_Gui_Pro_P(v,PL,EF);
                        elseif strcmpi(task,'Collect Statistics')                           
                            if strcmpi(specific_task,'Collect BrdU Stats')
                                v = 1;
                            else
                                v = 2;
                            end
%                             Collect_Gui_Pro_P(v,PL,app.directory);  %NEED TO FIX
                        elseif strcmpi(task,'Visualize')
                            app.V_App = V2(app,PL{1},EF{1});
                        else
                            
                        end
                    end
                end
            end
        end

        % Value changed function: DropStain
        function DropStainValueChanged(app, event)
            value = app.DropStain.Value;
            if strcmpi(value,'Raw')
                 app.DropTask.Items = strsplit('Convert to Individual Channels,Visualize and Cut Images',',');               
            else
                app.DropTask.Items = strsplit('Segment,Gather Statistics,Collect Statistics,Analyze,Visualize',',');
                
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.SelectedDataSetButton.Value == 0 && strcmpi(app.DropTask.Value,'Visualize and Cut Images')
               app.SelectedDataSetButton.Value = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectMainFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectMainFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainFolderButtonPushed, true);
            app.SelectMainFolderButton.Text = 'Select Main Folder';
            app.MainFolderTextAreaLabel = uilabel(app.UIFigure);
            app.MainFolderTextAreaLabel.Text = 'Main Folder';
            app.MainFolderTextArea = uitextarea(app.UIFigure);
            app.ListofDataSetsListBoxLabel = uilabel(app.UIFigure);
            app.ListofDataSetsListBoxLabel.Text = 'List of Data Sets';
            app.ListofDataSetsListBox = uilistbox(app.UIFigure);
            app.ListofDataSetsListBox.Items = {};
            app.ListofDataSetsListBox.Multiselect = 'on';
            app.ListofDataSetsListBox.Value = {};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            app.DropTask = uidropdown(app.UIFigure);
            app.DropTask.Items = {'Segment', 'Gather Statistics', 'Collect Statistics', 'Analyze', 'Visualize'};
            app.DropTask.Visible = 'off';
            app.DropTask.Value = 'Segment';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SelectedDataSetButton = uitogglebutton(app.ButtonGroup);
            app.SelectedDataSetButton.Text = 'Selected Data Set';
            app.AllDataSetsButton = uitogglebutton(app.ButtonGroup);
            app.AllDataSetsButton.Text = 'All Data Sets';
            app.AllDataSetsButton.Value = true;
            app.RuninParallelCheckBox = uicheckbox(app.UIFigure);
            app.RuninParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RuninParallelCheckBoxValueChanged, true);
            app.RuninParallelCheckBox.Text = 'Run in Parallel';
            app.MaxCoresEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCoresEditFieldLabel.Text = 'Max Cores';
            app.MaxCoresEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCoresEditField.Editable = 'off';
            app.MaxCoresEditField.Visible = 'off';
            app.MaxRamGBEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRamGBEditFieldLabel.Text = 'Max Ram (GB)';
            app.MaxRamGBEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxRamGBEditField.Limits = [0 1000];
            app.MaxRamGBEditField.Editable = 'off';
            app.MaxRamGBEditField.Visible = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Visible = 'off';
            app.DropDown.Value = {};
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DropStain = uidropdown(app.UIFigure);
            app.DropStain.Items = {'Raw', 'Architecture', 'Proliferation'};
            app.DropStain.ValueChangedFcn = createCallbackFcn(app, @DropStainValueChanged, true);
            app.DropStain.Visible = 'off';
            app.DropStain.Value = 'Architecture';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Liver_Segmentation_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end